classdef MATLAB_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DownloadDatasetButton      matlab.ui.control.Button
        SelectnationDropDownLabel  matlab.ui.control.Label
        SelectnationDropDown       matlab.ui.control.DropDown
        SavechartsCheckBox         matlab.ui.control.CheckBox
        UIAxes                     matlab.ui.control.UIAxes
        TextArea                   matlab.ui.control.TextArea
        SelectchartDropDownLabel   matlab.ui.control.Label
        SelectchartDropDown        matlab.ui.control.DropDown
        EditField                  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        confirmed_global_file;
        deaths_global_file;
        recovered_global_file;
        confirmed_global;
        deaths_global;
        recovered_global;
        nations_list;
        saving_enabled;
        provinces;
        chart_type;
        nation;
        num_changed_nation;
        chart;
        date;
        index_confirmed_global;
        index_deaths_global;
        index_recovered_global;
    end
    
    methods (Access = private)
        
        function [fitresult, xData, yData]  = createFit(~, data)
            %CREATEFIT(DAILY_CONFIRMED_GROWTH)
            %  Create a fit.
            %
            %  Data for 'untitled fit 1' fit:
            %      Y Output: daily_confirmed_growth
            %  Output:
            %      fitresult : a fit object representing the fit.
            %      gof : structure with goodness-of fit info.
            %
            %  See also FIT, CFIT, SFIT.
            
            %  Auto-generated by MATLAB on 09-Apr-2020 22:49:08
            
            %% Fit
            [xData, yData] = prepareCurveData( [], data );
            
            % Set up fittype and options.
            ft = fittype( 'poly9' );
            opts = fitoptions( 'Method', 'LinearLeastSquares' );
            opts.Normalize = 'on';
            opts.Robust = 'Bisquare';
            
            % Fit model to data.
            [fitresult, gof] = fit( xData, yData, ft, opts );
        end
        
        function [arrayNoZero] = deleteZeroEntries(~, array)
            arrayNoZero = array;
            for i = length(arrayNoZero) : -1 : 1
                if (arrayNoZero(i) == 0)
                    arrayNoZero(i) = [];
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % dataset file info
            FileInfo = dir("time_series_covid19_confirmed_global.csv");
            app.EditField.Value = "The dataset was downloaded on " +FileInfo.date+".If you want to download a new one, click the button below.";
            % set the chart type
            app.chart_type = ["Confirmed cases", "Deaths", "Recovered", "Daily % confirmed growth", "Daily % deaths growth", "Daily % recovered growth", "Fit daily confirmed cases growth", "Fit daily deaths growth", "Fit daily recovered growth"];
            % read the dataset
            app.confirmed_global = readtable('time_series_covid19_confirmed_global.csv');
            app.deaths_global = readtable('time_series_covid19_deaths_global.csv');
            app.recovered_global = readtable('time_series_covid19_recovered_global.csv');
            app.date = table2cell(app.confirmed_global(1, 5:end));
            app.nations_list = app.confirmed_global.Var2(2:end); 
            app.provinces = app.confirmed_global.Var1(2:end); 
            %delete multiple entries in nations_list array
            for i = 1:length(app.nations_list)
                if (strlength(app.provinces(i)) == 0)
                    %j = j+1;
                    nations(i) = app.nations_list(i);
                else
                    %j = j+1;
                    nations(i) = strcat(app.provinces(i),', ',app.nations_list(i));
                end
            end            
            app.SelectnationDropDown.Items = nations;
            app.SelectchartDropDown.Items = app.chart_type;
        end

        % Button pushed function: DownloadDatasetButton
        function DownloadDatasetButtonPushed(app, event)
            app.confirmed_global_file = websave('time_series_covid19_confirmed_global.csv','https://data.humdata.org/hxlproxy/api/data-preview.csv?url=https%3A%2F%2Fraw.githubusercontent.com%2FCSSEGISandData%2FCOVID-19%2Fmaster%2Fcsse_covid_19_data%2Fcsse_covid_19_time_series%2Ftime_series_covid19_confirmed_global.csv&filename=time_series_covid19_confirmed_global.csv');
            app.deaths_global_file = websave('time_series_covid19_deaths_global.csv','https://data.humdata.org/hxlproxy/api/data-preview.csv?url=https%3A%2F%2Fraw.githubusercontent.com%2FCSSEGISandData%2FCOVID-19%2Fmaster%2Fcsse_covid_19_data%2Fcsse_covid_19_time_series%2Ftime_series_covid19_deaths_global.csv&filename=time_series_covid19_deaths_global.csv');
            app.recovered_global_file = websave('time_series_covid19_recovered_global.csv','https://data.humdata.org/hxlproxy/api/data-preview.csv?url=https%3A%2F%2Fraw.githubusercontent.com%2FCSSEGISandData%2FCOVID-19%2Fmaster%2Fcsse_covid_19_data%2Fcsse_covid_19_time_series%2Ftime_series_covid19_recovered_global.csv&filename=time_series_covid19_recovered_global.csv');
        end

        % Value changed function: SelectnationDropDown
        function SelectnationDropDownValueChanged(app, event)
            app.nation = app.SelectnationDropDown.Value;
            %get the row index of the nation selected
            for i = 2:height(app.recovered_global)
                if (contains(app.nation, app.confirmed_global{i,2})  && contains(app.nation, app.confirmed_global{i,1}))
                    app.index_confirmed_global = i;
                end
                if (contains(app.nation, app.deaths_global{i,2}) && contains(app.nation, app.deaths_global{i,1}))
                    app.index_deaths_global = i;
                end
                if (contains(app.nation, app.recovered_global{i,2}) && contains(app.nation, app.recovered_global{i,1}))
                    app.index_recovered_global = i;
                end
            end
            SelectchartDropDownValueChanged(app, event);
        end

        % Value changed function: SavechartsCheckBox
        function SavechartsCheckBoxValueChanged(app, event)
            app.saving_enabled = app.SavechartsCheckBox.Value;
        end

        % Value changed function: SelectchartDropDown
        function SelectchartDropDownValueChanged(app, event)
            app.chart = app.SelectchartDropDown.Value;
            legend(app.UIAxes, "off");
            cla(app.UIAxes, 'reset');
            clear nation_confirmed nation_deaths nation_recovered;
            %get the tab row for the desidered nation
            nation_confirmed_tab = app.confirmed_global(app.index_confirmed_global,:);
            nation_deaths_tab = app.deaths_global(app.index_deaths_global,:);
            nation_recovered_tab = app.recovered_global(app.index_recovered_global,:);
            %convert the string tab to double
            j = 0;
            for i = 5: width(nation_confirmed_tab) 
                if (str2double(nation_confirmed_tab{1,i}) > 0)
                    j = j+1;
                    nation_confirmed (j) = str2double(nation_confirmed_tab{1,i});
                    nation_deaths (j) = str2double(nation_deaths_tab{1,i});
                    nation_recovered (j) = str2double(nation_recovered_tab{1,i});
                end
            end
            
            %delete zero entries in deaths array
            nation_deaths_no_zero = deleteZeroEntries(app, nation_deaths);
            
            %delete zero entries in recovered array
            nation_recovered_no_zero = deleteZeroEntries(app, nation_recovered);
           
            if (strcmp(app.chart,"Confirmed cases"))
                plot(app.UIAxes, nation_confirmed);
                set(app.UIAxes,'xtick',[1: 15: length(nation_confirmed)],'xticklabel', app.date(end-length(nation_confirmed)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation, " confirmed cases"));
                xlim(app.UIAxes, [1, (length(nation_confirmed)+1)]);
                ylim(app.UIAxes, [min(nation_confirmed), max(nation_confirmed)+1]);
                xlabel(app.UIAxes, 'Days since first confirmed case');
                ylabel(app.UIAxes, 'Confirmed cases');
            end
            if (strcmp(app.chart,"Deaths"))
                plot(app.UIAxes, nation_deaths);
                set(app.UIAxes,'xtick',[1: 15: length(nation_deaths)],'xticklabel', app.date(end-length(nation_deaths)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation, " deaths"));
                xlim(app.UIAxes, [1, (length(nation_deaths)+1)]);
                ylim(app.UIAxes, [min(nation_deaths), max(nation_deaths)+1]);
                xlabel(app.UIAxes,'Days since first confirmed case');
                ylabel(app.UIAxes,'Deaths');
            end
            if (strcmp(app.chart,"Recovered"))
                plot(app.UIAxes, nation_recovered);
                set(app.UIAxes,'xtick',[1: 15: length(nation_recovered)],'xticklabel', app.date(end-length(nation_recovered)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation, " recovered"));
                xlim(app.UIAxes, [1, (length(nation_recovered)+1)]);
                ylim(app.UIAxes, [min(nation_recovered), max(nation_recovered)+1]);
                xlabel(app.UIAxes,'Days since first confirmed case');
                ylabel(app.UIAxes,'Recovered');
            end
            if (strcmp(app.chart,"Daily % confirmed growth"))
                daily_confirmed_growth = [0 diff(nation_confirmed)];
                daily_perc_confirmed_growth = daily_confirmed_growth./nation_confirmed*100;
                plot(app.UIAxes, daily_perc_confirmed_growth);
                set(app.UIAxes,'xtick',[1: 15: length(daily_perc_confirmed_growth)],'xticklabel', app.date(end-length(daily_perc_confirmed_growth)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation, " daily % confirmed cases growth"));
                xlim(app.UIAxes, [1, (length(daily_perc_confirmed_growth)+1)]);
                ylim(app.UIAxes, [min(daily_perc_confirmed_growth), max(daily_perc_confirmed_growth)+1]);
                xlabel(app.UIAxes,'Days since first confirmed case');
                ylabel(app.UIAxes,'Daily % confirmed cases');
            end
            if (strcmp(app.chart,"Daily % deaths growth"))
                daily_deaths_growth = [0 diff(nation_deaths_no_zero)];
                daily_perc_deaths_growth = daily_deaths_growth./nation_deaths_no_zero*100;
                plot(app.UIAxes, daily_perc_deaths_growth);
                set(app.UIAxes,'xtick',[1: 15: length(daily_perc_deaths_growth)],'xticklabel', app.date(end-length(daily_perc_deaths_growth)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation, " daily % deaths growth"));
                xlim(app.UIAxes, [1, (length(daily_perc_deaths_growth)+1)]);
                ylim(app.UIAxes, [min(daily_perc_deaths_growth), max(daily_perc_deaths_growth)+1]);
                xlabel(app.UIAxes,'Days since first death');
                ylabel(app.UIAxes,'Daily % deaths cases');
            end
            if (strcmp(app.chart,"Daily % recovered growth"))
                daily_recovered_growth = [0 diff(nation_recovered_no_zero)];
                daily_perc_recovered_growth = daily_recovered_growth./nation_recovered_no_zero*100;
                plot(app.UIAxes, daily_perc_recovered_growth);
                set(app.UIAxes,'xtick',[1: 15: length(daily_perc_recovered_growth)],'xticklabel', app.date(end-length(daily_perc_recovered_growth)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation, " daily % recovered growth"));
                xlim(app.UIAxes, [1, (length(daily_perc_recovered_growth)+1)]);
                ylim(app.UIAxes, [min(daily_perc_recovered_growth), max(daily_perc_recovered_growth)+1]);
                xlabel(app.UIAxes,'Days since first recovered');
                ylabel(app.UIAxes,'Daily % recovered growth');
            end
            if (strcmp(app.chart,"Fit daily confirmed cases growth"))
                daily_confirmed_growth = [0 diff(nation_confirmed)];
                [fitresult, xData, yData] = app.createFit( daily_confirmed_growth);
                y_fit = feval(fitresult, xData);
                
                % Plot fit with data.
                scatter(app.UIAxes, 1: length(yData), yData);
                hold (app.UIAxes,"on");
                plot(app.UIAxes, y_fit);
                set(app.UIAxes,'xtick',[1: 15: length(y_fit)],'xticklabel', app.date(end-length(y_fit)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation," daily confirmed cases growth"));
                xlim(app.UIAxes, [1, (length(y_fit)+1)]);
                ylim(app.UIAxes, [min(yData), max(yData)+1]);
                xlabel(app.UIAxes,'Days since first confirmed case');
                ylabel(app.UIAxes,'Daily confirmed growth');
                legend(app.UIAxes, "on");
                legend(app.UIAxes, 'Daily confirmed growth', 'Fit', 'Location', 'NorthWest', 'Interpreter', 'none' );
                grid(app.UIAxes, "on");
            end      
            if (strcmp(app.chart,"Fit daily deaths growth"))
                daily_deaths_growth = [0 diff(nation_deaths_no_zero)];
                [fitresult, xData, yData] = app.createFit( daily_deaths_growth);
                y_fit = feval(fitresult,xData);
                
                % Plot fit with data.
                scatter(app.UIAxes, 1: length(yData), yData);
                hold (app.UIAxes,"on");
                plot(app.UIAxes, y_fit);
                set(app.UIAxes,'xtick',[1: 15: length(y_fit)],'xticklabel', app.date(end-length(y_fit)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation," daily deaths growth"));
                xlim(app.UIAxes, [1, (length(y_fit)+1)]);
                ylim(app.UIAxes, [min(yData), max(yData)+1]);
                xlabel(app.UIAxes,'Days since first death');
                ylabel(app.UIAxes,'Daily deaths growth');
                legend(app.UIAxes, "on");
                legend(app.UIAxes, 'Daily deaths growth', 'Fit', 'Location', 'NorthWest', 'Interpreter', 'none' );
                grid(app.UIAxes, "on");
            end      
            if (strcmp(app.chart,"Fit daily recovered growth"))
                daily_recovered_growth = [0 diff(nation_recovered_no_zero)];
                [fitresult, xData, yData] = app.createFit( daily_recovered_growth);
                y_fit = feval(fitresult, xData);
                
                % Plot fit with data.
                scatter(app.UIAxes, 1: length(yData), yData);
                hold (app.UIAxes,"on");
                plot(app.UIAxes, y_fit);
                set(app.UIAxes,'xtick',[1: 15: length(y_fit)],'xticklabel', app.date(end-length(y_fit)+1 : 15: end))
                title(app.UIAxes, strcat(app.nation," daily recovered growth"));
                xlim(app.UIAxes, [1, (length(y_fit)+1)]);
                ylim(app.UIAxes, [min(yData), max(yData)+1]);
                xlabel(app.UIAxes,'Days since first recovered case');
                ylabel(app.UIAxes,'Daily recovered growth');
                legend(app.UIAxes, "on");
                legend(app.UIAxes, 'Daily recovered growth', 'Fit', 'Location', 'NorthWest', 'Interpreter', 'none' );
                grid(app.UIAxes, "on");
            end 
                %if (app.saving_enabled)
                %    saveas(app.UIAxes,strcat('Charts/PNG/', app.nation,'.png'),'png');
                %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DownloadDatasetButton = uibutton(app.UIFigure, 'push');
            app.DownloadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDatasetButtonPushed, true);
            app.DownloadDatasetButton.Text = 'Download Dataset';
            app.SelectnationDropDownLabel = uilabel(app.UIFigure);
            app.SelectnationDropDownLabel.Text = 'Select nation';
            app.SelectnationDropDown = uidropdown(app.UIFigure);
            app.SelectnationDropDown.Items = {'Nation'};
            app.SelectnationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectnationDropDownValueChanged, true);
            app.SelectnationDropDown.Value = 'Nation';
            app.SavechartsCheckBox = uicheckbox(app.UIFigure);
            app.SavechartsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavechartsCheckBoxValueChanged, true);
            app.SavechartsCheckBox.Enable = 'off';
            app.SavechartsCheckBox.Visible = 'off';
            app.SavechartsCheckBox.Text = 'Save charts';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'The data are available at https://data.humdata.org/dataset/novel-coronavirus-2019-ncov-cases'};
            app.SelectchartDropDownLabel = uilabel(app.UIFigure);
            app.SelectchartDropDownLabel.Text = 'Select chart';
            app.SelectchartDropDown = uidropdown(app.UIFigure);
            app.SelectchartDropDown.Items = {'Chart type'};
            app.SelectchartDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectchartDropDownValueChanged, true);
            app.SelectchartDropDown.Value = 'Chart type';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Do you want to download an updated dataset?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end